classdef Maquina02MNED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MNEDUIFigure                 matlab.ui.Figure
        RelatorioMenu                matlab.ui.container.Menu
        SobreMenu                    matlab.ui.container.Menu
        AutoresMenu                  matlab.ui.container.Menu
        ISECMenu                     matlab.ui.container.Menu
        SairMenu                     matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GrelhaCheckBox               matlab.ui.control.CheckBox
        ExecutarButton               matlab.ui.control.Button
        MetodosNumericosButtonGroup  matlab.ui.container.ButtonGroup
        TodososMetodosButton         matlab.ui.control.ToggleButton
        AdamsBButton                 matlab.ui.control.ToggleButton
        RK4Button                    matlab.ui.control.ToggleButton
        EulerMButton                 matlab.ui.control.ToggleButton
        ode45Button                  matlab.ui.control.ToggleButton
        RK2Button                    matlab.ui.control.ToggleButton
        EulerButton                  matlab.ui.control.ToggleButton
        DadosdoPVIPanel              matlab.ui.container.Panel
        nSpinner                     matlab.ui.control.Spinner
        nSpinnerLabel                matlab.ui.control.Label
        eY0                          matlab.ui.control.NumericEditField
        y0EditFieldLabel             matlab.ui.control.Label
        eB                           matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        eA                           matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        eF                           matlab.ui.control.EditField
        ftyEditFieldLabel            matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app, []);
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            MNumericoSel = app.MetodosNumericosButtonGroup.SelectedObject.Text;

            % helper vector com cores mais faceis aos olhos
            colors = [[0, 0.4470, 0.7410]; ...
                [0.8500, 0.3250, 0.0980]; ...
                [0.9290, 0.6940, 0.1250]; ...
                [0.4940, 0.1840, 0.5560]; ...
                [0.4660, 0.6740, 0.1880]; ...
                [0.3010, 0.7450, 0.9330]; ...
                [0.6350, 0.0780, 0.1840]];

            try
                % Dados
                syms y(t)
                strF = app.eF.Value;
                f = @(t,y) eval(vectorize(strcat('@(t,y)', " ", strF)));
                a = app.eA.Value;
                b = app.eB.Value;
                n = app.nSpinner.Value;
                y0 = app.eY0.Value;

                try
                    z = f(t,y);
                    app.eF.BackgroundColor = "w";
                catch
                    app.eF.BackgroundColor = 'r';
                    throw(MException("MATLAB:Executar:badF","Introduza uma função em t e y"))
                end

                if (a > b || a==b)
                    app.eA.BackgroundColor = 'r';
                    if(a == b)
                        throw(MException('MATLAB:BadFunction','Valor de A tem de ser DIFERENTE que B'));
                    else
                        throw(MException('MATLAB:BadFunction','Valor de A tem de ser MENOR que B'));
                    end
                else
                    app.eA.BackgroundColor = "w";
                end

                if ~(isscalar(n) && n==floor(n) && n > 0)
                    app.nSpinner.BackgroundColor = 'r';
                    throw(MException('MATLAB:BadFunction','Introduza um valor para N correto'));
                else 
                    app.nSpinner.BackgroundColor = "w";
                end

                % solução exata do problema
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                % solução exata do problema (expressão numérica)
                g = @(t) eval(vectorize(char(sExata)));

                % resultados e tabela de resultados
                [t,yEuler]=NEuler(f,a,b,n,y0);
                [~, yEulerM]=NEulerMelhorado(f,a,b,n,y0);
                [~,yRK2]=RK2(f,a,b,n,y0);
                [~, yRK4]=RK4(f,a,b,n,y0);
                [~, yAdams]=AdamBashforth(f,a,b,n,y0);
                [~, yode]=ode45(f, t, y0);
                % ode 45 retorna um vetor t linhas x 1 coluna
                % de modo a usar igual a todos os outros "y's"
                yode = yode.';

                % calculo dos erros
                yExata = g(t);
                erroEuler = abs(yExata-yEuler);
                erroEulerM = abs(yExata-yEulerM);
                erroRK2 = abs(yExata-yRK2);
                erroRK4 = abs(yExata-yRK4);
                erroAdams = abs(yExata-yAdams);
                erroOde = abs(yExata-yode);

                plot(app.UIAxes,t,yExata,"-o", "Color",colors(1,:));
                hold(app.UIAxes,"on");
                legend(app.UIAxes,"Exata");

                A = [t.',yExata.'];
                nomeColunas = ["t","Exata"];

                switch MNumericoSel
                    case "Euler"
                        plot(app.UIAxes,t,yEuler,"-_","Color",colors(2,:));
                        A = [t.',yExata.',yEuler.',erroEuler.'];
                    case "Euler M"
                        plot(app.UIAxes,t,yEulerM, "-*","Color",colors(3,:));
                        A = [t.',yExata.',yEulerM.',erroEulerM.'];
                    case "RK2"
                        plot(app.UIAxes,t,yRK2, "-+","Color",colors(4,:));
                        A = [t.',yExata.',yRK2.',erroRK2.'];
                    case "RK4"
                        plot(app.UIAxes,t,yRK4, "-.","Color",colors(5,:));
                        A = [t.',yExata.',yRK4.',erroRK4.'];
                    case "Adams-B"
                        plot(app.UIAxes,t,yAdams, "-x","Color",colors(6,:));
                        A = [t.',yExata.',yAdams.',erroAdams.'];
                    case "ode45"
                        plot(app.UIAxes,t,yode, "-|","Color",colors(7,:));
                        A = [t.',yExata.', yode.', erroOde.'];
                    case "Todos os Métodos"
                        plot(app.UIAxes,t,yEuler, "-_","Color",colors(2,:));
                        plot(app.UIAxes,t,yEulerM, "-*","Color",colors(3,:));
                        plot(app.UIAxes,t,yRK2, "-+","Color",colors(4,:));
                        plot(app.UIAxes,t,yRK4, "-.","Color",colors(5,:));
                        plot(app.UIAxes,t,yAdams, "-x","Color",colors(6,:));
                        plot(app.UIAxes,t,yode, "-|","Color",colors(7,:));
                        legend(app.UIAxes,"Exata","Euler","EulerM","RK2","RK4","AdamsB","ode45");
                        A = [t.',yExata.',yEuler.', yEulerM.',yRK2.', yRK4.', yAdams.',yode.', ...
                            erroEuler.', erroEulerM.', erroRK2.', erroRK4.', erroAdams.',erroOde.'];
                        nomeColunas = ["t","Exata","Euler","EulerM","RK2","RK4","AdamsB","ode45", ...
                            "erroEuler", "erroEulerM", "erroRK2", "erroRK4", "erroAdamsB","erroOde"];
                    otherwise
                        uialert(app.MNEDUIFigure, "To implement", "Not done");
                end

                if ~(MNumericoSel=="Todos os Métodos")
                    legend(app.UIAxes,"Exata",MNumericoSel);
                    nomeColunas = ["t", "Exata",MNumericoSel,"erro"];
                end

                % representação gráfica
                hold(app.UIAxes,"off");
                grid(app.UIAxes, app.GrelhaCheckBox.Value);

                app.UITable.Data = array2table(A);
                app.UITable.ColumnName = nomeColunas;

            catch me %matlab exception
                errordlg(me.message, "Erro detetado", "modal");
            end
        end

        % Value changed function: GrelhaCheckBox
        function GrelhaCheckBoxValueChanged(app, event)
            grid(app.UIAxes, app.GrelhaCheckBox.Value);
        end

        % Menu selected function: RelatorioMenu
        function RelatorioMenuSelected(app, event)
            open("Metodos_Numericos_para_Equacoes_Diferenciais_Ordinarias_e_Problemas_de_Valor_Inicial.pdf");
        end

        % Menu selected function: SobreMenu
        function SobreMenuSelected(app, event)
            
        end

        % Menu selected function: ISECMenu
        function ISECMenuSelected(app, event)
            web("www.isec.pt")
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            autores
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            answer = questdlg("Quer Sair?", "Sair", "Sim", "Não", "Sim");
            if strcmp(answer, "Sim")
                delete(app.MNEDUIFigure)
            end
        end

        % Close request function: MNEDUIFigure
        function MNEDUIFigureCloseRequest(app, event)
            answer = questdlg("Quer Sair?", "Sair", "Sim", "Não", "Sim");
            if strcmp(answer, "Sim")
                delete(app.MNEDUIFigure)
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MNEDUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {415, 415};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MNEDUIFigure = uifigure('Visible', 'off');
            app.MNEDUIFigure.Name = 'MNED';
            app.MNEDUIFigure.CloseRequestFcn = createCallbackFcn(app, @MNEDUIFigureCloseRequest, true);
            app.MNEDUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.RelatorioMenu = uimenu(app.MNEDUIFigure);
            app.RelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatorioMenuSelected, true);
            app.RelatorioMenu.Text = 'Relatorio';
            app.SobreMenu = uimenu(app.MNEDUIFigure);
            app.SobreMenu.MenuSelectedFcn = createCallbackFcn(app, @SobreMenuSelected, true);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.ISECMenu = uimenu(app.SobreMenu);
            app.ISECMenu.MenuSelectedFcn = createCallbackFcn(app, @ISECMenuSelected, true);
            app.ISECMenu.Text = 'ISEC';
            app.SairMenu = uimenu(app.MNEDUIFigure);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.GridLayout = uigridlayout(app.MNEDUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftyEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y) =';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Value = 'y+exp(3*t)';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eA.Value = 1;
            app.bEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.bEditFieldLabel.Text = 'b';
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 1.5;
            app.y0EditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.y0EditFieldLabel.Text = 'y0';
            app.eY0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eY0.Value = 2;
            app.nSpinnerLabel = uilabel(app.DadosdoPVIPanel);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.DadosdoPVIPanel);
            app.nSpinner.Limits = [1 Inf];
            app.nSpinner.Value = 2;
            app.MetodosNumericosButtonGroup = uibuttongroup(app.LeftPanel);
            app.MetodosNumericosButtonGroup.Title = 'Métodos Numéricos';
            app.EulerButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.RK2Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.RK2Button.Text = 'RK2';
            app.ode45Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.ode45Button.Text = 'ode45';
            app.EulerMButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.EulerMButton.Text = 'Euler M';
            app.RK4Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.RK4Button.Text = 'RK4';
            app.AdamsBButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.AdamsBButton.Text = 'Adams-B';
            app.TodososMetodosButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.TodososMetodosButton.Text = 'Todos os Métodos';
            app.ExecutarButton = uibutton(app.LeftPanel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.GrelhaCheckBox = uicheckbox(app.LeftPanel);
            app.GrelhaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrelhaCheckBoxValueChanged, true);
            app.GrelhaCheckBox.Text = 'Grelha';
            app.GrelhaCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.MNEDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maquina02MNED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MNEDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MNEDUIFigure)
        end
    end
end